classdef InterferenzUndSchwebung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ResetButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        FrequencySliderLabel  matlab.ui.control.Label
        FrequencySlider       matlab.ui.control.Slider
        PhaseSliderLabel      matlab.ui.control.Label
        PhaseSlider           matlab.ui.control.Slider
        SoundSwitchLabel      matlab.ui.control.Label
        SoundSwitch           matlab.ui.control.Switch
    end

    
    properties (Access = private)
        player; % Description
        freq;
        x;
        phase;
        wave1;
        wave2;
        waveSum;
        plotHdl;
        audio;
    end
    
    methods (Access = private)
        
        % My own littel funktions
        function yData=wave(~,xData,frequency,phaseShift)
            yData=sin(frequency*xData-phaseShift);
        end
        
        function sumwave(app)
            app.w=app.wave(app.x,1,0)+app.wave(app.x,app.freq,app.phase);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.player=audioplayer(1,440*100);
            app.x=0:0.1:45*pi;
            app.freq=1;
            app.phase=0;
            app.wave1=app.wave(app.x,1,0);
            app.wave2=app.wave(app.x,1,0);
            app.waveSum=app.wave1+app.wave2;
            hold(app.UIAxes, 'on');
            app.plotHdl(1)=plot(app.UIAxes,app.x,app.wave1,'color',[0 0.5 0],'linewidth',1,'linestyle','-');
            app.plotHdl(2)=plot(app.UIAxes,app.x,app.wave2,'color',[0 0 1],'linewidth',1,'linestyle','-');
            app.plotHdl(3)=plot(app.UIAxes,app.x,app.waveSum,'color',[1 0 0],'linewidth',3,'linestyle','-');
            legend(app.UIAxes,'f_1(x))','f_2(x)','f_1(x)+f_2(x)')
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.freq=1;
            app.phase=0;
            app.wave1=app.wave(app.x,1,0);
            app.wave2=app.wave(app.x,1,0);
            app.waveSum=app.wave1+app.wave2;
            set(app.plotHdl(1),'xdata',app.x,'ydata',app.wave1);
            set(app.plotHdl(2),'xdata',app.x,'ydata',app.wave2);
            set(app.plotHdl(3),'xdata',app.x,'ydata',app.waveSum);
            stop(app.player)
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            app.freq = event.Value;
            app.wave2=app.wave(app.x,app.freq,app.phase);
            app.waveSum=app.wave1+app.wave2;
            set(app.plotHdl(2),'xdata',app.x,'ydata',app.wave2);
            set(app.plotHdl(3),'xdata',app.x,'ydata',app.waveSum);
            if app.audio
                xTime=0:1/100:440*5;
                stop(app.player);
                app.player=audioplayer(0.5*sin(2*pi*xTime)+0.5*sin(2*pi*app.freq*xTime-app.phase),440*100);
                play(app.player);
            end
            
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.phase = event.Value;
            app.wave2=app.wave(app.x,app.freq,app.phase);
            app.waveSum=app.wave1+app.wave2;
            set(app.plotHdl(2),'xdata',app.x,'ydata',app.wave2);
            set(app.plotHdl(3),'xdata',app.x,'ydata',app.waveSum);
            if app.audio
                xTime=0:1/100:440*5;
                stop(app.player);
                app.player=audioplayer(0.5*sin(2*pi*xTime)+0.5*sin(2*pi*app.freq*xTime-app.phase),440*100);
                play(app.player);
            end
            
        end

        % Value changed function: SoundSwitch
        function SoundSwitchValueChanged(app, event)
            value = app.SoundSwitch.Value;
            if strcmp(value,'On')
                app.audio=true;
            else
                app.audio=false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Interferenz und Schwebung')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 140];
            app.UIAxes.YLim = [-2.2 2.2];
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [0.05 1];
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 1;
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [0 7];
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.SoundSwitchLabel = uilabel(app.UIFigure);
            app.SoundSwitchLabel.Text = 'Sound';
            app.SoundSwitch = uiswitch(app.UIFigure, 'slider');
            app.SoundSwitch.ValueChangedFcn = createCallbackFcn(app, @SoundSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterferenzUndSchwebung

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end